classdef Spherical_solenoid < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MagneticFluxDensity      matlab.ui.Figure
        Panel                    matlab.ui.container.Panel
        XEditFieldLabel          matlab.ui.control.Label
        XEditField               matlab.ui.control.NumericEditField
        YEditFieldLabel          matlab.ui.control.Label
        YEditField               matlab.ui.control.NumericEditField
        ZEditFieldLabel          matlab.ui.control.Label
        ZEditField               matlab.ui.control.NumericEditField
        CalculateButton          matlab.ui.control.Button
        BxmGsEditFieldLabel      matlab.ui.control.Label
        BxmGsEditField           matlab.ui.control.NumericEditField
        CurrentAEditFieldLabel   matlab.ui.control.Label
        CurrentAEditField        matlab.ui.control.NumericEditField
        BymGsEditFieldLabel      matlab.ui.control.Label
        BymGsEditField           matlab.ui.control.NumericEditField
        BzmGsEditFieldLabel      matlab.ui.control.Label
        BzmGsEditField           matlab.ui.control.NumericEditField
        BtotalmGsEditFieldLabel  matlab.ui.control.Label
        BtotalmGsEditField       matlab.ui.control.NumericEditField
        CoordinatesLabel         matlab.ui.control.Label
        ResultsLabel             matlab.ui.control.Label
        TabGroup                 matlab.ui.container.TabGroup
        YZTab                    matlab.ui.container.Tab
        UIAxesYZ_BY              matlab.ui.control.UIAxes
        UIAxesYZ_BZ              matlab.ui.control.UIAxes
        QuiverTab                matlab.ui.container.Tab
        UIAxesQuiverYZ           matlab.ui.control.UIAxes
        XYTab                    matlab.ui.container.Tab
        UIAxesXY_BZ              matlab.ui.control.UIAxes
        DTab                     matlab.ui.container.Tab
        UIAxes3D                 matlab.ui.control.UIAxes
        ProcessIndicator         matlab.ui.control.TextArea
    end

    
    properties (Access = private)
        Property % Description
    end
    
    methods (Access = private)
% %         %--------------------------------------------------------------------------------------------------------
% %         function [Bx,By,Bz] = Magnetic_field_solenoid_spherical1(~,r0,theta0,phi0,I)
% %             %The following program calculates the magnetic field
% %             %at the point P(r0,theta0,phi0)
% %             %which is caused by a cicular loop of radius "b"
% %             %at the height of z0.
% %             
% %             %that carries the current "I".
% %             
% %             %Magnetic field unit is milligauss.
% %             
% %             %The constant of µ0(multiplied by 10^7 so the answer would be in milligause):
% %             mu0=4*pi;
% %             
% %             
% %             
% %             %raduis of the spherical coil
% %             B=5;
% %             %number of loops in the solenoid
% %             n=50;
% %             dBxt=0;
% %             dByt=0;
% %             dBzt=0;
% %             syms  phi z0
% %             %The radius of the loop at the height of z0
% %             b=sqrt(B.^2-z0.^2);
% %             
% %             % dl in the spherical coordinates is " b*d(phi)*a(phi) ".
% %             % Which will be the following in the cartesian coordinates after transformation:
% %             dl=     [-b.*sin(phi)              , b.*cos(phi)               , 0              ];
% %             
% %             % r=r0 a(r) + theta0 a(theta) + phi0 a(phi)
% %             % Its transformed form in cartesian is the following:
% %             r=      [r0.*sin(theta0).*cos(phi0), r0.*sin(theta0).*sin(phi0), r0.*cos(theta0)];
% %             % r'= b a(ro) + phi a(phi) + z0 a(z)
% %             r_prime=[+b.*cos(phi)              , +b.*sin(phi)              , z0             ];
% %             
% %             % R is the vector from dl ( a point on the wire) and the desired point
% %             R=r - r_prime;
% %             R_distance= (R(1,1)^2+R(1,2)^2+R(1,3)^2)^(1/2);
% %             R_distance1=matlabFunction( R_distance);
% %             
% %             
% %             Cross_result=cross(dl,R);
% %             Cross_result1=matlabFunction(Cross_result);
% %             
% %             
% %             z01=linspace(-B,B,n);
% %             for z02= z01
% %                 
% %                 
% %                 R_distance2=R_distance1(phi,z02);
% %                 Cross_result2=Cross_result1(phi,z02);
% %                 
% %                 %     µ0 *  I    / dl x R  \
% %                 % db= ________ * | ______  | *  d(phi)
% %                 %                |      3  |
% %                 %      4pi       \   |R|   /
% %                 
% %                 
% %                 dbx=mu0.*I.*Cross_result2(1,1)/(4.*pi.*R_distance2.^3);
% %                 dby=mu0.*I.*Cross_result2(1,2)/(4.*pi.*R_distance2.^3);
% %                 dbz=mu0.*I.*Cross_result2(1,3)/(4.*pi.*R_distance2.^3);
% %                 
% %                 dBxt=dBxt+dbx;
% %                 dByt=dByt+dby;
% %                 dBzt=dBzt+dbz;
% %             end
% %             
% %             
% %             if dBxt~=0 && isnan(dBxt)==0
% %                 Bxt=matlabFunction(dBxt);
% %                 Bx = integral(Bxt,0,2*pi);
% %             else
% %                 Bx=0;
% %             end
% %             
% %             if dByt~=0 && isnan(dByt)==0
% %                 Byt=matlabFunction(dByt);
% %                 By = integral(Byt,0,2*pi);
% %             else
% %                 By=0;
% %             end
% %             
% %             if dBzt~=0 && isnan(dBzt)==0
% %                 Bzt=matlabFunction(dBzt);
% %                 Bz =integral(Bzt,0,2*pi);
% %             else
% %                 Bz=0;
% %             end
% %         end
% %         %--------------------------------------------------------------------------------------------------------
% %         function [Bx,By,Bz] = Magnetic_field_solenoid_cartesian1(app,x,y,z,I)
% %             
% %             phi = atan2(y,x);
% %             theta= atan2(sqrt(x.^2 + y.^2),z);
% %             r = sqrt(x.^2 + y.^2 + z.^2);
% %             [Bx,By,Bz] = Magnetic_field_solenoid_spherical1(app,r,theta,phi,I);
% %             
% %             
% %         end
        %--------------------------------------------------------------------------------------------------------
        function  ContourGraphYZ_BY1(app,I)
            load BY.mat;
            
            
            %BY is calculated for the current of 1 so it should be multiplied by "I" to match the given current
            %by the user.
            BY1=I.*BY./2;
            
            
            %For graphing the BY component on the Y-Z plane.
            
            n=14;
            step=0.35;
            
            zp=-n:step:n;
            yp=-n:step:n;
            
            limy1=min(min(BY1));
            limy2=max(max(BY1));
            T=linspace(limy1,limy2,100);
            
            contour(app.UIAxesYZ_BY,yp,zp,BY1,T);
            
            
            axis(app.UIAxesYZ_BY,[-n n -n n]);
            pbaspect(app.UIAxesYZ_BY,[1 1 1]);
            
            xlabel(app.UIAxesYZ_BY,'Y-axis','fontsize',14);
            ylabel(app.UIAxesYZ_BY,'Z-axis','fontsize',14);
            title(app.UIAxesYZ_BY,'BY component on x=0 plane','fontsize',14);
            colorbar(app.UIAxesYZ_BY,'location','eastoutside','fontsize',14);
            
        end
        %--------------------------------------------------------------------------------------------------------
        function  ContourGraphYZ_BZ1(app,I)
            load BZ.mat;
            
            
            %BZ is calculated for the current of 1 so it should be multiplied by "I" to match with the given current
            %by the user.
            BZ1=I.*BZ./2;
            
            
            %For graphing the BZ component on the Y-Z plane.
            
            n=14;
            step=0.35;
            
            zp=-n:step:n;
            yp=-n:step:n;
            
            limz1=min(min(BZ1));
            limz2=max(max(BZ1));
            T2=linspace(limz1,limz2,100);
            
            contour(app.UIAxesYZ_BZ,yp,zp,BZ1,T2);
            
            
            axis(app.UIAxesYZ_BZ,[-n n -n n]);
            pbaspect(app.UIAxesYZ_BZ,[1 1 1]);
            
            
            xlabel(app.UIAxesYZ_BZ,'Y-axis','fontsize',14);
            ylabel(app.UIAxesYZ_BZ,'Z-axis','fontsize',14);
            title(app.UIAxesYZ_BZ,'BZ component on x=0 plane','fontsize',14);
            colorbar(app.UIAxesYZ_BZ,'location','eastoutside','fontsize',14);
            
        end
        %--------------------------------------------------------------------------------------------------------
        function QuiverGraphYZ1(app)
            load BY.mat;
            load BZ.mat;
            
            BY=BY./2;
            BZ=BZ./2;
            
            %For graphing the BY-BZ velocity plot (Quiver).
            n=14;
            step=0.35;
            
            zp=-n:step:n;
            yp=-n:step:n;
            
            quiver(app.UIAxesQuiverYZ,yp,zp,BY,BZ,4);
            
            
            pbaspect(app.UIAxesQuiverYZ,[1 1 1]);
            axis(app.UIAxesQuiverYZ,[-8 8 -8 8]);
            
            
            xlabel(app.UIAxesQuiverYZ,'Y-axis','fontsize',14);
            ylabel(app.UIAxesQuiverYZ,'Z-axis','fontsize',14);
            title(app.UIAxesQuiverYZ,'Magnetic field shape on the x=0 plane','fontsize',14);
            
        end
        %--------------------------------------------------------------------------------------------------------
        function  ContourGraphXY1(app,I)
            
            load BZ2.mat;
            
            
            %BZ2 is calculated for the current of 1 so it should be multiplied by "I" to match with the given current
            %by the user.
            BZ=I.*BZ2./2;
            
            %For graphing the BZ component on the X-Y plane
            
            
            n=14;
            step=0.35;
            
            xp=-n:step:n;
            yp=-n:step:n;
            
            limz1=min(min(BZ));
            limz2=max(max(BZ));
            
            T=linspace(limz1,limz2,100);
            contour(app.UIAxesXY_BZ,xp,yp,BZ,T);
            
            xlabel(app.UIAxesXY_BZ,'X-axis','fontsize',14);
            ylabel(app.UIAxesXY_BZ,'Y-axis','fontsize',14);
            title(app.UIAxesXY_BZ,'BZ component on z=0 plane','fontsize',14);
            colorbar(app.UIAxesXY_BZ,'location','eastoutside','fontsize',14);
            
            
        end
        %---------------------------------------------------------------------------------------------------------------
        function  solenoid_shape_YZ1(app)
            theta=linspace(0,2*pi,50);
            r=5;
            x1=r.*cos(theta);
            y1=r.*sin(theta);
            
            hold (app.UIAxesYZ_BY,'on')
            hold (app.UIAxesYZ_BZ,'on')
            
            plot(app.UIAxesYZ_BY,x1,y1,'k');
            plot(app.UIAxesYZ_BZ,x1,y1,'k');
            
            for z0=-5:0.5:5
                b=sqrt(25-z0.^2);
                x2=-b+0.1:0.1:b+0.1;
                k=size(x2,2);
                y2=ones(1,k).*z0;
                plot(app.UIAxesYZ_BY,x2,y2,'k','LineWidth',2);
                plot(app.UIAxesYZ_BZ,x2,y2,'k','LineWidth',2);
            end
        end
        %---------------------------------------------------------------------------------------------------------------
        function solenoid_shape_XY1(app)
            theta=linspace(0,2*pi,50);
            R=linspace(0,5,10);
            
            hold (app.UIAxesXY_BZ,'on')
            for r=R
                x2=r.*cos(theta);
                y2=r.*sin(theta);
                plot(app.UIAxesXY_BZ,x2,y2,'k','LineWidth',2);
            end
        end
        %---------------------------------------------------------------------------------------------------------------
        function solenoid_shape_3D1(app)
            
            t=0:pi/100:2*pi;
            b=5;
            hold(app.UIAxes3D ,'on')
            lim=12;
            xlim(app.UIAxes3D ,[-lim,lim]);
            ylim(app.UIAxes3D ,[-lim,lim]);
            zlim(app.UIAxes3D ,[-lim,lim]);
            view(app.UIAxes3D,3);
            pbaspect(app.UIAxes3D ,[1,1,1]);
            for z0=-b:0.5:b
                r=sqrt(b.^2-z0.^2);
                x=r.*sin(t);
                y=r.*cos(t);
                z=0*t;
                plot3(app.UIAxes3D,x,y,z+z0,'k');
            end
            
            view(app.UIAxes3D ,3);
            
        end
        %---------------------------------------------------------------------------------------------------------------
        function Field_shape_3D1(app)
            
            
            r=2;
            a=3;
            t=0:pi/100:2*pi;
            
            for j=1:4
                r=r*j;
                x=(r.*cos(t)+r);
                y=(r.*cos(t)+r);
                z=a.*r.*sin(t);
                
                hold (app.UIAxes3D,'on')
                
                for i=1:9
                    phi=i*pi/3;
                    X=x.*cos(phi)-y.*sin(phi);
                    Y=x.*sin(phi)+y.*cos(phi);
                    Z=z;
                    plot3(app.UIAxes3D,X,Y,Z,'b')
                    
                end
            end
            
            axis(app.UIAxes3D,[-30 30 -30 30 -30 30]);
            pbaspect(app.UIAxes3D,[1,1,1]);
            
            view(app.UIAxes3D,3);
            title(app.UIAxes3D,'A representation of the magnetic field around the solenoid');
        end
    end
    
    % Calculating the magnetic flux density caused by a spherical solenoid in any point in space
    % By S. Ali Madad Soltani & M. Amani
    % Prof. H. Ali Akbarian
    % K.N Toosi University Of Technology
    % Winter of 1398

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
            app.XEditField.Value=3;
            app.YEditField.Value=4;
            app.ZEditField.Value=5;
            app.CurrentAEditField.Value=1;
            %------------------------------------------------------------------------------------------------------------
            ContourGraphYZ_BY1(app,1);
            ContourGraphYZ_BZ1(app,1);
            solenoid_shape_YZ1(app);
            QuiverGraphYZ1(app);
            ContourGraphXY1(app,1);
            solenoid_shape_XY1(app);
            Field_shape_3D1(app);
            solenoid_shape_3D1(app);
            
            % Calculating the magnetic flux density caused by a spherical solenoid in any point in space
            % By S. Ali Madad Soltani & M. Amani
            % Prof. H. Ali Akbarian
            % K.N Toosi University Of Technology
            % Winter of 1398
        end

        % Button pushed function: CalculateButton
        function CalculateButtonPushed(app, event)
            app.ProcessIndicator.Value='Calculating...';

            drawnow;
            x=app.XEditField.Value;
            y=app.YEditField.Value;
            z=app.ZEditField.Value;
            I=app.CurrentAEditField.Value;


            [Bx,By,Bz] = Magnetic_field_solenoid_cartesian(x,y,z,I);
            
            app.BxmGsEditField.Value=Bx;
            app.BymGsEditField.Value=By;
            app.BzmGsEditField.Value=Bz;
            
            Bt=sqrt(Bx^2+By^2+Bz^2);
            app.BtotalmGsEditField.Value=Bt;
            
            app.ProcessIndicator.Value='Done!';
            
            ContourGraphYZ_BY1(app,I);
            ContourGraphYZ_BZ1(app,I);
            solenoid_shape_YZ1(app);
            
            ContourGraphXY1(app,I);
            solenoid_shape_XY1(app);
            
            
            % Calculating the magnetic flux density caused by a spherical solenoid in any point in space
            % By S. Ali Madad Soltani & M. Amani
            % Prof. H. Ali Akbarian
            % K.N Toosi University Of Technology
            % Winter of 1398
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.MagneticFluxDensity = uifigure('Visible', 'off');
            app.MagneticFluxDensity.Name = 'Magnetic flux density caused by spherical solenoid';
            app.Panel = uipanel(app.MagneticFluxDensity);
            app.XEditFieldLabel = uilabel(app.Panel);
            app.XEditFieldLabel.Text = 'X';
            app.XEditField = uieditfield(app.Panel, 'numeric');
            app.YEditFieldLabel = uilabel(app.Panel);
            app.YEditFieldLabel.Text = 'Y';
            app.YEditField = uieditfield(app.Panel, 'numeric');
            app.ZEditFieldLabel = uilabel(app.Panel);
            app.ZEditFieldLabel.Text = 'Z';
            app.ZEditField = uieditfield(app.Panel, 'numeric');
            app.CalculateButton = uibutton(app.Panel, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.CalculateButton.Text = 'Calculate';
            app.BxmGsEditFieldLabel = uilabel(app.Panel);
            app.BxmGsEditFieldLabel.Text = 'Bx(mGs)';
            app.BxmGsEditField = uieditfield(app.Panel, 'numeric');
            app.CurrentAEditFieldLabel = uilabel(app.Panel);
            app.CurrentAEditFieldLabel.Text = 'Current(A)';
            app.CurrentAEditField = uieditfield(app.Panel, 'numeric');
            app.BymGsEditFieldLabel = uilabel(app.Panel);
            app.BymGsEditFieldLabel.Text = 'By(mGs)';
            app.BymGsEditField = uieditfield(app.Panel, 'numeric');
            app.BzmGsEditFieldLabel = uilabel(app.Panel);
            app.BzmGsEditFieldLabel.Text = 'Bz(mGs)';
            app.BzmGsEditField = uieditfield(app.Panel, 'numeric');
            app.BtotalmGsEditFieldLabel = uilabel(app.Panel);
            app.BtotalmGsEditFieldLabel.Text = 'B total(mGs)';
            app.BtotalmGsEditField = uieditfield(app.Panel, 'numeric');
            app.CoordinatesLabel = uilabel(app.Panel);
            app.CoordinatesLabel.Text = 'Coordinates';
            app.ResultsLabel = uilabel(app.Panel);
            app.ResultsLabel.Text = 'Results';
            app.TabGroup = uitabgroup(app.Panel);
            app.YZTab = uitab(app.TabGroup);
            app.YZTab.Title = 'Y-Z';
            app.UIAxesYZ_BY = uiaxes(app.YZTab);
            title(app.UIAxesYZ_BY, 'Title')
            xlabel(app.UIAxesYZ_BY, 'X')
            ylabel(app.UIAxesYZ_BY, 'Y')
            app.UIAxesYZ_BZ = uiaxes(app.YZTab);
            title(app.UIAxesYZ_BZ, 'Title')
            xlabel(app.UIAxesYZ_BZ, 'X')
            ylabel(app.UIAxesYZ_BZ, 'Y')
            app.QuiverTab = uitab(app.TabGroup);
            app.QuiverTab.Title = 'Quiver';
            app.UIAxesQuiverYZ = uiaxes(app.QuiverTab);
            title(app.UIAxesQuiverYZ, 'Title')
            xlabel(app.UIAxesQuiverYZ, 'X')
            ylabel(app.UIAxesQuiverYZ, 'Y')
            app.XYTab = uitab(app.TabGroup);
            app.XYTab.Title = 'X-Y';
            app.UIAxesXY_BZ = uiaxes(app.XYTab);
            title(app.UIAxesXY_BZ, 'Title')
            xlabel(app.UIAxesXY_BZ, 'X')
            ylabel(app.UIAxesXY_BZ, 'Y')
            app.DTab = uitab(app.TabGroup);
            app.DTab.Title = '3D';
            app.UIAxes3D = uiaxes(app.DTab);
            title(app.UIAxes3D, 'Title')
            xlabel(app.UIAxes3D, 'X')
            ylabel(app.UIAxes3D, 'Y')
            app.ProcessIndicator = uitextarea(app.Panel);
            app.ProcessIndicator.Editable = 'off';
            % Show the figure after all components are created
            app.MagneticFluxDensity.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Spherical_solenoid

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.MagneticFluxDensity)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MagneticFluxDensity)
        end
    end
end